classdef interface_code_barre < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InterfacecodebarreKiewskyDecourtUIFigure  matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        ChargeruneimageButton          matlab.ui.control.Button
        DtectionautomatiqueButton      matlab.ui.control.Button
        chiffre1                       matlab.ui.control.NumericEditField
        chiffre2                       matlab.ui.control.NumericEditField
        chiffre3                       matlab.ui.control.NumericEditField
        chiffre4                       matlab.ui.control.NumericEditField
        chiffre5                       matlab.ui.control.NumericEditField
        chiffre6                       matlab.ui.control.NumericEditField
        chiffre7                       matlab.ui.control.NumericEditField
        chiffre8                       matlab.ui.control.NumericEditField
        chiffre9                       matlab.ui.control.NumericEditField
        chiffre10                      matlab.ui.control.NumericEditField
        chiffre11                      matlab.ui.control.NumericEditField
        chiffre12                      matlab.ui.control.NumericEditField
        chiffre13                      matlab.ui.control.NumericEditField
        EtatducodebarreEditFieldLabel  matlab.ui.control.Label
        EtatducodebarreEditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        img; % Description
    end
    

    methods (Access = private)

        % Callback function
        function loadCallback(app, event)
            
            
        end

        % Button pushed function: ChargeruneimageButton
        function ChargeruneimageButtonPushed(app, event)
            [name, path] = uigetfile('*.jpg','*.png');
            app.img = strcat(path,name);
            title(app.UIAxes, [app.img]);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            imshow(app.img,'Parent',app.UIAxes);
        end

        % Callback function: DtectionautomatiqueButton, 
        % EtatducodebarreEditField, chiffre1, chiffre10, chiffre11, 
        % chiffre12, chiffre13, chiffre2, chiffre3, chiffre4, chiffre5, 
        % chiffre6, chiffre7, chiffre8, chiffre9
        function DtectionmanuelleButtonPushed(app, event)
            [chiffres,seg,new_p1,new_p2,x_centre,y_centre]=detection_manuel(app.img);
           
            plot(app.UIAxes,x_centre,y_centre,'yx');
            plot(app.UIAxes,seg(1,:),seg(2,:),'g');
            plot(app.UIAxes,new_p1(1),new_p1(2),'ro');
            plot(app.UIAxes,new_p2(1),new_p2(2),'ro');
            
            if chiffres == 0
                app.chiffre1.Value = 0;
                app.chiffre2.Value = 0;
                app.chiffre3.Value = 0;
                app.chiffre4.Value = 0;
                app.chiffre5.Value = 0;
                app.chiffre6.Value = 0;
                app.chiffre7.Value = 0;
                app.chiffre8.Value = 0;
                app.chiffre9.Value = 0;
                app.chiffre10.Value = 0;
                app.chiffre11.Value = 0;
                app.chiffre12.Value = 0;
                app.chiffre13.Value = 0;
                app.EtatducodebarreEditField.Value = 'Mauvais code barre';
                app.chiffre1.FontColor = 'red';
                app.chiffre2.FontColor = 'red';
                app.chiffre3.FontColor = 'red';
                app.chiffre4.FontColor = 'red';
                app.chiffre5.FontColor = 'red';
                app.chiffre6.FontColor = 'red';
                app.chiffre7.FontColor = 'red';
                app.chiffre8.FontColor = 'red';
                app.chiffre9.FontColor = 'red';
                app.chiffre10.FontColor = 'red';
                app.chiffre11.FontColor = 'red';
                app.chiffre12.FontColor = 'red';
                app.chiffre13.FontColor = 'red';
            else
                app.chiffre1.Value = chiffres(1);
                app.chiffre2.Value = chiffres(2);
                app.chiffre3.Value = chiffres(3);
                app.chiffre4.Value = chiffres(4);
                app.chiffre5.Value = chiffres(5);
                app.chiffre6.Value = chiffres(6);
                app.chiffre7.Value = chiffres(7);
                app.chiffre8.Value = chiffres(8);
                app.chiffre9.Value = chiffres(9);
                app.chiffre10.Value = chiffres(10);
                app.chiffre11.Value = chiffres(11);
                app.chiffre12.Value = chiffres(12);
                app.chiffre13.Value = chiffres(13);
                app.EtatducodebarreEditField.Value = 'Bon code barre';
                app.chiffre1.FontColor = 'green';
                app.chiffre2.FontColor = 'green';
                app.chiffre3.FontColor = 'green';
                app.chiffre4.FontColor = 'green';
                app.chiffre5.FontColor = 'green';
                app.chiffre6.FontColor = 'green';
                app.chiffre7.FontColor = 'green';
                app.chiffre8.FontColor = 'green';
                app.chiffre9.FontColor = 'green';
                app.chiffre10.FontColor = 'green';
                app.chiffre11.FontColor = 'green';
                app.chiffre12.FontColor = 'green';
                app.chiffre13.FontColor = 'green';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InterfacecodebarreKiewskyDecourtUIFigure = uifigure;
            app.InterfacecodebarreKiewskyDecourtUIFigure.Name = 'Interface code barre - Kiewsky & Decourt';
            app.UIAxes = uiaxes(app.InterfacecodebarreKiewskyDecourtUIFigure);
            title(app.UIAxes, 'Appuyer sur le bouton "Charger une image"')
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.ChargeruneimageButton = uibutton(app.InterfacecodebarreKiewskyDecourtUIFigure, 'push');
            app.ChargeruneimageButton.ButtonPushedFcn = createCallbackFcn(app, @ChargeruneimageButtonPushed, true);
            app.ChargeruneimageButton.Text = 'Charger une image';
            app.DtectionautomatiqueButton = uibutton(app.InterfacecodebarreKiewskyDecourtUIFigure, 'push');
            app.DtectionautomatiqueButton.ButtonPushedFcn = createCallbackFcn(app, @DtectionmanuelleButtonPushed, true);
            app.DtectionautomatiqueButton.Text = 'Détection automatique';
            app.chiffre1 = uieditfield(app.InterfacecodebarreKiewskyDecourtUIFigure, 'numeric');
            app.chiffre1.ValueChangedFcn = createCallbackFcn(app, @DtectionmanuelleButtonPushed, true);
            app.chiffre1.Editable = 'off';
            app.chiffre2 = uieditfield(app.InterfacecodebarreKiewskyDecourtUIFigure, 'numeric');
            app.chiffre2.ValueChangedFcn = createCallbackFcn(app, @DtectionmanuelleButtonPushed, true);
            app.chiffre2.Editable = 'off';
            app.chiffre2.Value = 1;
            app.chiffre3 = uieditfield(app.InterfacecodebarreKiewskyDecourtUIFigure, 'numeric');
            app.chiffre3.ValueChangedFcn = createCallbackFcn(app, @DtectionmanuelleButtonPushed, true);
            app.chiffre3.Editable = 'off';
            app.chiffre3.Value = 2;
            app.chiffre4 = uieditfield(app.InterfacecodebarreKiewskyDecourtUIFigure, 'numeric');
            app.chiffre4.ValueChangedFcn = createCallbackFcn(app, @DtectionmanuelleButtonPushed, true);
            app.chiffre4.Editable = 'off';
            app.chiffre4.Value = 3;
            app.chiffre5 = uieditfield(app.InterfacecodebarreKiewskyDecourtUIFigure, 'numeric');
            app.chiffre5.ValueChangedFcn = createCallbackFcn(app, @DtectionmanuelleButtonPushed, true);
            app.chiffre5.Editable = 'off';
            app.chiffre5.Value = 4;
            app.chiffre6 = uieditfield(app.InterfacecodebarreKiewskyDecourtUIFigure, 'numeric');
            app.chiffre6.ValueChangedFcn = createCallbackFcn(app, @DtectionmanuelleButtonPushed, true);
            app.chiffre6.Editable = 'off';
            app.chiffre6.Value = 5;
            app.chiffre7 = uieditfield(app.InterfacecodebarreKiewskyDecourtUIFigure, 'numeric');
            app.chiffre7.ValueChangedFcn = createCallbackFcn(app, @DtectionmanuelleButtonPushed, true);
            app.chiffre7.Editable = 'off';
            app.chiffre8 = uieditfield(app.InterfacecodebarreKiewskyDecourtUIFigure, 'numeric');
            app.chiffre8.ValueChangedFcn = createCallbackFcn(app, @DtectionmanuelleButtonPushed, true);
            app.chiffre8.Editable = 'off';
            app.chiffre8.Value = 6;
            app.chiffre9 = uieditfield(app.InterfacecodebarreKiewskyDecourtUIFigure, 'numeric');
            app.chiffre9.ValueChangedFcn = createCallbackFcn(app, @DtectionmanuelleButtonPushed, true);
            app.chiffre9.Editable = 'off';
            app.chiffre9.Value = 7;
            app.chiffre10 = uieditfield(app.InterfacecodebarreKiewskyDecourtUIFigure, 'numeric');
            app.chiffre10.ValueChangedFcn = createCallbackFcn(app, @DtectionmanuelleButtonPushed, true);
            app.chiffre10.Editable = 'off';
            app.chiffre10.Value = 8;
            app.chiffre11 = uieditfield(app.InterfacecodebarreKiewskyDecourtUIFigure, 'numeric');
            app.chiffre11.ValueChangedFcn = createCallbackFcn(app, @DtectionmanuelleButtonPushed, true);
            app.chiffre11.Editable = 'off';
            app.chiffre11.Value = 9;
            app.chiffre12 = uieditfield(app.InterfacecodebarreKiewskyDecourtUIFigure, 'numeric');
            app.chiffre12.ValueChangedFcn = createCallbackFcn(app, @DtectionmanuelleButtonPushed, true);
            app.chiffre12.Editable = 'off';
            app.chiffre13 = uieditfield(app.InterfacecodebarreKiewskyDecourtUIFigure, 'numeric');
            app.chiffre13.ValueChangedFcn = createCallbackFcn(app, @DtectionmanuelleButtonPushed, true);
            app.chiffre13.Editable = 'off';
            app.chiffre13.Value = 1;
            app.EtatducodebarreEditFieldLabel = uilabel(app.InterfacecodebarreKiewskyDecourtUIFigure);
            app.EtatducodebarreEditFieldLabel.Text = 'Etat du code barre';
            app.EtatducodebarreEditField = uieditfield(app.InterfacecodebarreKiewskyDecourtUIFigure, 'text');
            app.EtatducodebarreEditField.ValueChangedFcn = createCallbackFcn(app, @DtectionmanuelleButtonPushed, true);
            app.EtatducodebarreEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = interface_code_barre

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InterfacecodebarreKiewskyDecourtUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InterfacecodebarreKiewskyDecourtUIFigure)
        end
    end
end